classdef Video2PointCloud < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideoToPointCloudUIFigure  matlab.ui.Figure
        StartConversionButton      matlab.ui.control.Button
        VideotoPointCloudConversionLabel  matlab.ui.control.Label
        PecentageofConversionCompleteGaugeLabel  matlab.ui.control.Label
        PecentageofConversionCompleteGauge  matlab.ui.control.LinearGauge
        Label                      matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        PathoffileEditFieldLabel   matlab.ui.control.Label
        PathoffileEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        pathOfVideoFile % Description
        angle % The current angle the camera is viewing from
        angleToChangeDirection % Description
        choiceOfCamera % Description
        counterForFrames % Description
        noOfFrames % Description
        newFrame % Description
    end
    
    properties (Access = private)
        Property6 % Description
    end
    
    methods (Access = public)
        
        
        function result = determineAngleOfCam(app)
            
            if(app.choiceOfCamera == 1 || app.choiceOfCamera == -1)
                
                if(app.angle > app.angleToChangeDirection)
                    signForAngle = - 1;
                    if(app.choiceOfCamera == -1)  app.angleToChangeDirection = app.angleToChangeDirection + pi/10;
                    end
                elseif(app.angle < app.angleToChangeDirection && app.angle > -app.angleToChangeDirection)
                    nothing = 0; %#ok<NASGU>
                elseif(app.angle < -app.angleToChangeDirection)
                    signForAngle  = 1;
                end
                
                app.angle = app.angle + signForAngle*2*pi/app.noOfFrames;
                
            elseif(app.choiceOfCamera == 0)
                
                app.angle = 2*pi*app.counterForFrames/app.noOfFrames;
                result = app.angle;
            end
            
        end
        
        
        function newImage = d3PlotOfPixels(app)
            x = app.newFrame;
            x2 = rgb2gray(x);
            
            sizeImage = size(x);
            rows = (sizeImage(1)*sizeImage(2));
            map = zeros(rows,3);
            c = zeros(rows,3);
            l = 0;
            value = int16(0); %#ok<NASGU>
            
            tic;
            for i = 1:sizeImage(1)
                for j = 1:sizeImage(2)
                    value = int16(x2(i,j))-125;
                    l = l+1;
                    %         c(l,:) = [x(i,j,1),x(i,j,2),x(i,j,3)];
                    %         map(l,:) = [int32(i),int32(j),value];
                    c(l,1) = x(i,j,1);
                    c(l,2) = x(i,j,2);
                    c(l,3) = x(i,j,3);
                    map(l,1) = int32(i);
                    map(l,2) = int32(j);
                    map(l,3) = value;
                end
            end
            timing_to_loop = toc %#ok<NOPRT,NASGU>
            
            %             tic;
            % totalFrames = 5559;
            %  angle = 2*pi*app.frameNumber/totalFrames;
            
            A2 = [1 0 0 0;...
                0 cos(app.angle) -sin(app.angle) 0;...
                0 sin(app.angle) cos(app.angle) 0;...
                0 0 0 1];
            tform = affine3d(A2);
            
            map2 =transformPointsForward(tform,map);
            %             timing_to_transform_points = toc %#ok<NOPRT,NASGU>
            
            f1 = figure('visible','off');
            set(f1, 'Position',  [100, 100, 875,656 ])
            pcshow(map2,uint8(c));
            az = 90;
            el = 90;
            view(az, el);
            % view(-5,0,100);
            axis off;
            %  xlabel('x');
            %  ylabel('y');
            %  zlabel('z');
            
            F = getframe(f1);
            clear map;
            clear c;
            clear map2;
            newImage = F.cdata;
            clear F;
            
            
            %             %  figureName = strcat('vidFrame_', num2str(frameNumber),'.jpg');
            %             %  saveas(f1,figureName);
            %             %  pause(0.6);
            %             %
            %             %  newImage = imread(figureName);
            %             %  imshow(newImage);
            %             %   delete(figureName);
            
            close all;
            
            clc;


        end
        
    end
    
    methods (Access = private)
        
        
    end


    methods (Access = private)

        % Button pushed function: StartConversionButton
        function StartConversionButtonPushed(app, event)
            
            v = VideoReader(app.pathOfVideoFile);
            newFileName = strcat('pointCloud-',app.pathOfVideoFile);
            writerObj = VideoWriter(newFileName);
            writerObj.FrameRate = v.Framerate;
            open(writerObj);
            
            totalNoOfFrames = floor(v.Duration * v.Framerate);
            start_frame = floor(14*v.Framerate) + 20;
            end_frame = totalNoOfFrames - 383;
            start_frame = 1;
              end_frame = totalNoOfFrames; % totalNoOfFrames;
            app.noOfFrames = end_frame - start_frame;
            overAllToc = 0;
            app.counterForFrames = 0;
            signForAngle = 1;
            app.angle = 0;
            app.angleToChangeDirection = pi/10;
            app.choiceOfCamera = 0;
            app.Lamp.Color =  [0.00,1.00,0.00];
            for i  = start_frame: end_frame % could be 1:totalNoOfFrames
                
                app.counterForFrames = app.counterForFrames +1;
                app.newFrame  = read(v,i);
                app.angle = determineAngleOfCam(app);
                outputFrame = d3PlotOfPixels(app);
                writeVideo(writerObj,outputFrame);
                app.newFrame = [];
                outputFrame = [];
                app.PecentageofConversionCompleteGauge.Value = app.counterForFrames*100/(app.noOfFrames+1);
                strcat('frames_written: ',num2str(app.counterForFrames),'/',num2str(app.noOfFrames))
                
            end
            close(writerObj);
            app.Lamp.Color =  [1.00,0.00,0.00];
        end

        % Value changed function: PathoffileEditField
        function PathoffileEditFieldValueChanged(app, event)
            app.pathOfVideoFile = event.Value
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VideoToPointCloudUIFigure = uifigure;
            app.VideoToPointCloudUIFigure.Name = 'Video To Point Cloud';
            setAutoResize(app, app.VideoToPointCloudUIFigure, true)
            app.StartConversionButton = uibutton(app.VideoToPointCloudUIFigure, 'push');
            app.StartConversionButton.ButtonPushedFcn = createCallbackFcn(app, @StartConversionButtonPushed, true);
            app.StartConversionButton.Text = 'Start Conversion';
            app.VideotoPointCloudConversionLabel = uilabel(app.VideoToPointCloudUIFigure);
            app.VideotoPointCloudConversionLabel.Text = 'Video to Point Cloud Conversion ';
            app.PecentageofConversionCompleteGaugeLabel = uilabel(app.VideoToPointCloudUIFigure);
            app.PecentageofConversionCompleteGaugeLabel.Text = 'Pecentage of Conversion Complete';
            app.PecentageofConversionCompleteGauge = uigauge(app.VideoToPointCloudUIFigure, 'linear');
            app.Label = uilabel(app.VideoToPointCloudUIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.VideoToPointCloudUIFigure);
            app.PathoffileEditFieldLabel = uilabel(app.VideoToPointCloudUIFigure);
            app.PathoffileEditFieldLabel.Text = 'Path of file';
            app.PathoffileEditField = uieditfield(app.VideoToPointCloudUIFigure, 'text');
            app.PathoffileEditField.ValueChangedFcn = createCallbackFcn(app, @PathoffileEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Video2PointCloud()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VideoToPointCloudUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideoToPointCloudUIFigure)
        end
    end
end